classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        UITable             matlab.ui.control.Table
        DistanceLabel       matlab.ui.control.Label
        EditField           matlab.ui.control.EditField
        BrowsefolderButton  matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
        DrawmodelButton     matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SurfacePlot matlab.graphics.primitive.Surface

        imageDataArray
        camerastxt
        imagestxt

        origin
        sideLengths
        floor_walls

        waitforGUI = false
        mywaitbar
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UIAxes.Visible = 'off';
           app.UITable.Visible = 'off';
           app.DrawmodelButton.Visible = 'off';
        end

        % Button pushed function: DrawmodelButton
        function DrawmodelButtonPushed(app, event)

            % Sample 3D coordinates
            app.DrawmodelButton.Visible = 'off';
            app.EditField.Visible = 'off';
            app.UIAxes.Visible = 'on';
            app.UITable.Visible = 'on';


            % [origin, sideLengths, X_floor, Y_floor, Z_floor] = get_boxes();
            numObjects = size(app.sideLengths,1);
            vertices = zeros(8,3,numObjects);

            faces = [
                1 2 3 4;  % Bottom face
                5 6 7 8;  % Top face
                1 2 6 5;  % Side face 1
                2 3 7 6;  % Side face 2
                3 4 8 7;  % Side face 3
                4 1 5 8   % Side face 4
            ];


            for i=1:numObjects
                 % Extract coordinates of the origin
                    x = app.origin(1);
                    y = app.origin(2);
                    z = app.origin(3);
                    
                    % Extract side lengths
                    sideLengthX = app.sideLengths(1);
                    sideLengthY = app.sideLengths(2);
                    sideLengthZ = app.sideLengths(3);
                    
                    % Define the vertices of the box
                    vertices = [
                        x - sideLengthX/2, y - sideLengthY/2, z - sideLengthZ/2;
                        x + sideLengthX/2, y - sideLengthY/2, z - sideLengthZ/2;
                        x + sideLengthX/2, y + sideLengthY/2, z - sideLengthZ/2;
                        x - sideLengthX/2, y + sideLengthY/2, z - sideLengthZ/2;
                        x - sideLengthX/2, y - sideLengthY/2, z + sideLengthZ/2;
                        x + sideLengthX/2, y - sideLengthY/2, z + sideLengthZ/2;
                        x + sideLengthX/2, y + sideLengthY/2, z + sideLengthZ/2;
                        x - sideLengthX/2, y + sideLengthY/2, z + sideLengthZ/2
                    ];


                    % Define the faces of the box
                    faces = [
                        1, 2, 3, 4;
                        5, 6, 7, 8;
                        1, 2, 6, 5;
                        2, 3, 7, 6;
                        3, 4, 8, 7;
                        4, 1, 5, 8
                    ];
                patch('Parent', app.UIAxes, 'Vertices', vertices(:,:,i), 'Faces', faces, 'FaceColor', 'blue', 'FaceAlpha', 0.5,'ButtonDownFcn',@mouse_click);
            end
            

            %Plot the floor and walls
            for i=1:size(app.floor_walls,2)
                [X, Y, Z] = app.floor_walls{i};
                patch('Parent', app.UIAxes,'XData', X, 'YData', Y, 'ZData', Z, 'faceColor', 'grey', 'FaceAlpha', 0.3);
            end


            function mouse_click(~,event)
                % Get the clicked points' coordinates
                coords = event.IntersectionPoint;
                % do something with the coordinates (e.g. add coordinates to table)
                numpoints = size(app.UITable.Data,1);
                if numpoints ~=1 %The first point
                    %delete previos points
                    app.UITable.Data = [];
                    %add new point
                    app.UITable.Data(1,:) = [coords(1),coords(2), coords(3)]; 
                else %The second point

                    %Save datapoint
                    app.UITable.Data(2,:) = [coords(1),coords(2), coords(3)]; 
                    xData = [app.UITable.Data(1,1) app.UITable.Data(2,1)];
                    yData =  [app.UITable.Data(1,2) app.UITable.Data(2,2) ];
                    zData =  [app.UITable.Data(1,3) app.UITable.Data(2,3) ];

                    % Find and delete existing line objects
                    delete(findobj(app.UIAxes, 'Type', 'line'));

                    %Plot new line
                    hold(app.UIAxes, 'on'); 
                    plot3(app.UIAxes, xData ,yData,zData, 'Color', 'red');
                    hold(app.UIAxes, 'off');

                    % Calculate the distance between the two clicked points
                    distance = sqrt((diff(xData))^2 + (diff(yData))^2 + (diff(zData))^2);

                    % Display the distance in a text label
                    app.DistanceLabel.Text = sprintf('Distance [m] = %.2f', distance);
                end
            end
            
        end

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            % Get the path of the MATLAB file
            matlabFilePath = mfilename('fullpath');
            [folderPath, ~, ~] = fileparts(matlabFilePath);
            
            % Display a dialog box to choose a folder
            folderPath = uigetdir(folderPath, 'Select a folder');
            if isequal(folderPath, 0)
                disp('No folder selected.');
                return;
            end
            
            % Read image files from the "images" folder
            imageFolderPath = fullfile(folderPath, 'images');
            imageFileNames = dir(fullfile(imageFolderPath, '*.jpg'));
            numImages = numel(imageFileNames);

            % Read and save the .txt files from the "parameters" folder
            parametersFolderPath = fullfile(folderPath, 'parameters');
            txtFileNames = dir(fullfile(parametersFolderPath, '*.txt'));
            
            % Initialize a cell array to store the image data
            app.imageDataArray = cell(numImages, 1);
            
            % Read and store the image data for each selected image
            h = waitbar(0,'Please wait...');
            for i = 1:numImages
                imagePath = fullfile(imageFolderPath, imageFileNames(i).name);
                app.imageDataArray{i} = imagePath;
                waitbar(i / (numImages + numel(txtFileNames)));
                newText = sprintf('%d / %d Images uploaded', i, numImages);
                app.EditField.Value = newText;
            end
           
            % Get the current text in the edit field
            currentText = app.EditField.Value;
            

            for j = 1:numel(txtFileNames)
                txtFilePath = fullfile(parametersFolderPath, txtFileNames(j).name);
                [~, txtFileName, ~] = fileparts(txtFilePath);
                disp(txtFileName);
                % txtData = fileread(txtFilePath);
                if txtFileName == "cameras"
                    app.camerastxt = readmatrix(txtFilePath);
                elseif txtFileName == "images"
                    app.imagestxt = readmatrix(txtFilePath);
                end
                    

                newText = sprintf('%d / %d Textfiles uploaded', j, numel(txtFileNames));
                waitbar((i+j )/ (numImages + numel(txtFileNames)));
                % Append the new text below the old text with a newline character
                updatedText = sprintf('%s \n\n %s', currentText, newText);
                app.EditField.Value = updatedText;
            end
            close(h);
            
            app.waitforGUI = 1;
            app.EditField.Value = "Please wait while the 3D model is buildt";
            app.BrowsefolderButton.Visible = "off";
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D-map')
            xlabel(app.UIAxes, ' [m]')
            ylabel(app.UIAxes, ' [m]')
            zlabel(app.UIAxes, 'Height [m]')
            app.DrawmodelButton = uibutton(app.UIFigure, 'push');
            app.DrawmodelButton.ButtonPushedFcn = createCallbackFcn(app, @DrawmodelButtonPushed, true);
            app.DrawmodelButton.Text = 'Draw model';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'X [m]'; 'Y [m]'; 'Z [m]'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end